classdef CA_FSM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RuleEditFieldLabel  matlab.ui.control.Label
        RuleEditField       matlab.ui.control.NumericEditField
        STATUS              matlab.ui.control.Label
        Image               matlab.ui.control.Image
        InterestingOnes30546094150250Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RuleEditField
        function RuleEditFieldValueChanged(app, event)
            value = app.RuleEditField.Value;
            app.STATUS.Text = 'Calculating...';
            app.STATUS.FontColor = [0.8667 0.1745 0.1882];
            drawnow;     
            [FSM_array, status] = CA_FSM_PIPE_00(value);
            if(isstring(status))
                app.STATUS.Text = status;
                drawnow;
            else
                app.Image.ImageSource = repmat(FSM_array, 1, 1, 3);
                drawnow;
                app.STATUS.Text = 'Calculated';
                app.STATUS.FontColor = [0.4667 0.6745 0.1882];
                drawnow;
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RuleEditFieldLabel = uilabel(app.UIFigure);
            app.RuleEditFieldLabel.Text = 'Rule';
            app.RuleEditField = uieditfield(app.UIFigure, 'numeric');
            app.RuleEditField.ValueChangedFcn = createCallbackFcn(app, @RuleEditFieldValueChanged, true);
            app.STATUS = uilabel(app.UIFigure);
            app.STATUS.Text = 'Enter The Rule';
            app.Image = uiimage(app.UIFigure);
            app.InterestingOnes30546094150250Label = uilabel(app.UIFigure);
            app.InterestingOnes30546094150250Label.Text = 'Interesting Ones: 30, 54, 60, 94,150, 250';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CA_FSM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end